classdef base_station < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        xmlfileEditField        matlab.ui.control.EditField
        xmlfileEditFieldLabel   matlab.ui.control.Label
        DISCONNECTButton        matlab.ui.control.Button
        CONNECTButton           matlab.ui.control.Button
        BaudRateEditField       matlab.ui.control.NumericEditField
        BaudRateEditFieldLabel  matlab.ui.control.Label
        COMPortEditField        matlab.ui.control.EditField
        COMPortEditFieldLabel   matlab.ui.control.Label
    end

    
    properties (Access = public)
        Property % My public properties
        s; % Serial port object
        dialect
        mavlink
        f
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CONNECTButton
        function connectSerial(app, event)
            port = char(app.COMPortEditField.Value);
            baud = double(app.BaudRateEditField.Value);
            xmlPath = char(app.xmlfileEditField.Value);
            
            try
                app.dialect = mavlinkdialect(xmlPath);
                app.mavlink = mavlinkio(app.dialect);
                disp(['Dialect ', xmlPath, ' loaded']);
            catch e
                uialert(app.UIFigure, ['Error loading dialect: ', e.message], 'MAVLink Error');
            end
            
            try
                app.s = serialport(port, baud);
                disp(['Connected to ', port, ' at ', num2str(baud), ' baud']);
            catch e
                uialert(app.UIFigure, ['Error connecting: ', e.message], 'Connection Error');
            end

            try
                app.f = parfeval(@workerFunction, 0, app.s);  % 0 output arguments
                disp(['Started a thread worker for reception']);
            catch e
                uialert(app.UIFigure, ['Error starting a worker: ', e.message], 'Thread Error');
            end
              
        end

        % Button pushed function: DISCONNECTButton
        function disconnectSerial(app, event)
            try
                cancel(app.f);
                disp(['Stopped a thread worker']);
            catch e
                uialert(app.UIFigure, ['Error canceling a worker: ', e.message], 'Thread Error');
            end
            
            port = char(app.COMPortEditField.Value);
            try
                delete(app.s)
                disp(['Serial port ' port ' disconnected']);
            catch e
                uialert(app.UIFigure, ['Error disconnecting: ', e.message], 'Connection Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.COMPortEditFieldLabel = uilabel(app.UIFigure);
            app.COMPortEditFieldLabel.Text = 'COM Port';
            app.COMPortEditField = uieditfield(app.UIFigure, 'text');
            app.COMPortEditField.Placeholder = 'COM';
            app.COMPortEditField.Value = 'COM17';
            app.BaudRateEditFieldLabel = uilabel(app.UIFigure);
            app.BaudRateEditFieldLabel.Text = 'BaudRate';
            app.BaudRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaudRateEditField.Limits = [0 Inf];
            app.BaudRateEditField.ValueDisplayFormat = '%d';
            app.BaudRateEditField.Value = 115200;
            app.CONNECTButton = uibutton(app.UIFigure, 'push');
            app.CONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @connectSerial, true);
            app.CONNECTButton.Text = 'CONNECT';
            app.DISCONNECTButton = uibutton(app.UIFigure, 'push');
            app.DISCONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @disconnectSerial, true);
            app.DISCONNECTButton.Text = 'DISCONNECT';
            app.xmlfileEditFieldLabel = uilabel(app.UIFigure);
            app.xmlfileEditFieldLabel.Text = '.xml file';
            app.xmlfileEditField = uieditfield(app.UIFigure, 'text');
            app.xmlfileEditField.Placeholder = './mavmc.xml';
            app.xmlfileEditField.Value = './mavmc.xml';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = base_station

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end